classdef toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SpeechtoCodeLabel     matlab.ui.control.Label
        BrowseButton          matlab.ui.control.Button
        FilePathField         matlab.ui.control.EditField
        RecordButton          matlab.ui.control.Button
        RecordIndicationLamp  matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function selectFile(app, event)
            [~, filePath] = uigetfile('*');
            disp(filePath)
            app.FilePathField.Value = filePath;
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            filePath = app.FilePathField.Value;
            if ~isfile(filePath)
                errordlg('File not found', 'Error');
            end
            app.RecordIndicationLamp.Color = 'red';
            drawnow();
            listen(filePath);
            app.RecordIndicationLamp.Color = 'green';
            drawnow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpeechtoCodeLabel = uilabel(app.UIFigure);
            app.SpeechtoCodeLabel.Text = 'Speech-to-Code';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.FilePathField = uieditfield(app.UIFigure, 'text');
            app.FilePathField.Value = 'Enter path of MATLAB File to write to';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Start Recording';
            app.RecordIndicationLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end